classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CalculateButton_5               matlab.ui.control.Button
        CalculateButton_4               matlab.ui.control.Button
        CalculateButton_3               matlab.ui.control.Button
        CalculateButton_2               matlab.ui.control.Button
        CalculateButton                 matlab.ui.control.Button
        CloudBaseHeightkmEditField      matlab.ui.control.NumericEditField
        CloudBaseHeightkmEditFieldLabel  matlab.ui.control.Label
        TotalAttenuationdBEditField     matlab.ui.control.NumericEditField
        TotalAttenuationdBEditFieldLabel  matlab.ui.control.Label
        FreeSpacePathLossdBEditField    matlab.ui.control.NumericEditField
        FreeSpacePathLossdBEditFieldLabel  matlab.ui.control.Label
        CloudAttenuationdBEditField     matlab.ui.control.NumericEditField
        CloudAttenuationdBEditFieldLabel  matlab.ui.control.Label
        AtmosphericAttenuationdBEditField  matlab.ui.control.NumericEditField
        AtmosphericAttenuationdBEditFieldLabel  matlab.ui.control.Label
        RainAttenuationdBEditField      matlab.ui.control.NumericEditField
        RainAttenuationdBEditFieldLabel  matlab.ui.control.Label
        LiquidWaterDensityinCloudsgm3EditField  matlab.ui.control.NumericEditField
        LiquidWaterDensityinCloudsgm3EditFieldLabel  matlab.ui.control.Label
        LiquidWaterrTemperatureinCloudsCEditField  matlab.ui.control.NumericEditField
        LiquidWaterrTemperatureinCloudsCEditFieldLabel  matlab.ui.control.Label
        RainRatemmhrEditField           matlab.ui.control.NumericEditField
        RainRatemmhrEditFieldLabel      matlab.ui.control.Label
        RelativeHumidityEditField       matlab.ui.control.NumericEditField
        RelativeHumidityEditFieldLabel  matlab.ui.control.Label
        SurfacePressurehPaEditField     matlab.ui.control.NumericEditField
        SurfacePressurehPaEditFieldLabel  matlab.ui.control.Label
        SurfaceTemperatureCEditField    matlab.ui.control.NumericEditField
        SurfaceTemperatureCEditFieldLabel  matlab.ui.control.Label
        PolarizationTypeDropDown        matlab.ui.control.DropDown
        PolarizationTypeDropDownLabel   matlab.ui.control.Label
        PathLengthkmEditField           matlab.ui.control.NumericEditField
        PathLengthkmEditFieldLabel      matlab.ui.control.Label
        ElevationAngleDegreeEditField   matlab.ui.control.NumericEditField
        ElevationAngleDegreeEditFieldLabel  matlab.ui.control.Label
        UplinkFrequencyGHzEditField     matlab.ui.control.NumericEditField
        UplinkFrequencyGHzEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function calculateButtonPushed(app, event)
            
            Area=app.AReaEditField.Value;
            rain=app.rainmmEditField.Value;


            volume=Area*rain;
            app.volumeEditField.Value=volume;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
           % Get user input values from the UI fields
            rain_rate = app.RainRatemmhrEditField.Value; % mm/hr
            frequency = app.UplinkFrequencyGHzEditField.Value; % GHz
            theta = app.ElevationAngleDegreeEditField.Value; % degrees
            L = app.PathLengthkmEditField.Value; % km
        
            % Convert polarization dropdown selection to string
            polarizationType = app.PolarizationTypeDropDown.Value; 

            disp("Debugging Values Before Function Call:");
            disp("Rain Rate: " + class(rain_rate) + " - " + num2str(rain_rate));
            disp("Frequency: " + class(frequency) + " - " + num2str(frequency));
            disp("Elevation Angle: " + class(theta) + " - " + num2str(theta));
            disp("Path Length: " + class(L) + " - " + num2str(L));
            disp("Polarization Type: " + class(polarizationType) + " - " + polarizationType);
        
            if ~isscalar(rain_rate) || ~isscalar(frequency) || ~isscalar(theta) || ~isscalar(L)
                uialert(app.UIFigure, 'Inputs must be single numbers, not arrays.', 'Input Error');
                return;
            end
            attenuation=Rain_Attenuation(rain_rate,frequency,polarizationType,theta*pi/180,L);
            
            disp("Function Output: " + class(attenuation) + " - " + num2str(attenuation));

            app.RainAttenuationdBEditField.Value=attenuation;
        end

        % Button pushed function: CalculateButton_4
        function CalculateButton_4Pushed(app, event)
            f=app.UplinkFrequencyGHzEditField.Value;
            d=app.PathLengthkmEditField.Value;

            fspl=FSPL(f,d);
            app.FreeSpacePathLossdBEditField.Value=fspl;
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            temp_surface=app.SurfaceTemperatureCEditField.Value;
            pressure_surface=app.SurfacePressurehPaEditField.Value;
            humidity=app.RelativeHumidityEditField.Value;
            f=app.UplinkFrequencyGHzEditField.Value;
            theta=app.ElevationAngleDegreeEditField.Value;

            % Saturated water vapor pressure
            F_sat = Goff_Gratch(temp_surface);
            
            % Surface water density (Absolute humidity)
            row= (0.018/8.314)*(pressure_surface./(temp_surface+273));
            
            % Water vapor pressure
            pressure_water= 0.01*(F_sat.*humidity);
            
            % Dry air pressure
            pressure_air= pressure_surface - pressure_water;

            alu=Oxygen_Attenuation(f,temp_surface,pressure_surface,row,pressure_air,pressure_water,theta);
            kochu=WaterVapor_Attenuation(f,pressure_air,pressure_water,theta);

            kola=abs(alu+kochu);
            app.AtmosphericAttenuationdBEditField.Value=kola;


        end

        % Button pushed function: CalculateButton_3
        function CalculateButton_3Pushed(app, event)
            frequency=app.UplinkFrequencyGHzEditField.Value;
            theta=app.ElevationAngleDegreeEditField.Value;
            water_temp= app.LiquidWaterrTemperatureinCloudsCEditField.Value;
            water_dens= app.LiquidWaterDensityinCloudsgm3EditField.Value;
            height=app.CloudBaseHeightkmEditField.Value;

            mango= Cloud_Attenuation(frequency,water_temp,water_dens,height,theta);
            app.CloudAttenuationdBEditField.Value=mango;
        end

        % Button pushed function: CalculateButton_5
        function CalculateButton_5Pushed(app, event)
            R=app.RainRatemmhrEditField.Value;
            Ts=app.SurfaceTemperatureCEditField.Value;
            Ps=app.SurfacePressurehPaEditField.Value;
            RH=app.RelativeHumidityEditField.Value;
            water_temp_Clouds=app.LiquidWaterrTemperatureinCloudsCEditField.Value;
            water_dens_Cloud=app.LiquidWaterDensityinCloudsgm3EditField.Value;
            H=app.CloudBaseHeightkmEditField.Value;
            f=app.UplinkFrequencyGHzEditField.Value;
            theta=app.ElevationAngleDegreeEditField.Value;
            L=app.PathLengthkmEditField.Value;
            pol=app.PolarizationTypeDropDown.Value;

            % Saturated water vapor pressure
            F_sat = Goff_Gratch(Ts);            
            % Surface water density (Absolute humidity)
            row= (0.018/8.314)*(Ps./(Ts+273));
            % Water vapor pressure
            pressure_water= 0.01*(F_sat.*RH);
            % Dry air pressure
            pressure_air= Ps - pressure_water;

            a=Cloud_Attenuation(f,water_temp_Clouds,water_dens_Cloud,H,theta);
            app.CloudAttenuationdBEditField.Value=a;

            b=Oxygen_Attenuation(f,Ts,Ps,row,pressure_air,pressure_water,theta);
            c=WaterVapor_Attenuation(f,pressure_air,pressure_water,theta);
            d=abs(b+c);
            app.AtmosphericAttenuationdBEditField.Value=d;

            fspl=FSPL(f,L);
            app.FreeSpacePathLossdBEditField.Value=fspl;

            e=Rain_Attenuation(R,f,pol,theta*pi/180,L);
            app.RainAttenuationdBEditField.Value=e;

            Total=a+b+e+fspl;
            app.TotalAttenuationdBEditField.Value=Total;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UplinkFrequencyGHzEditFieldLabel = uilabel(app.UIFigure);
            app.UplinkFrequencyGHzEditFieldLabel.Text = 'Uplink Frequency (GHz)';
            app.UplinkFrequencyGHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.ElevationAngleDegreeEditFieldLabel = uilabel(app.UIFigure);
            app.ElevationAngleDegreeEditFieldLabel.Text = 'Elevation Angle (Degree)';
            app.ElevationAngleDegreeEditField = uieditfield(app.UIFigure, 'numeric');
            app.PathLengthkmEditFieldLabel = uilabel(app.UIFigure);
            app.PathLengthkmEditFieldLabel.Text = 'Path Length (km)';
            app.PathLengthkmEditField = uieditfield(app.UIFigure, 'numeric');
            app.PolarizationTypeDropDownLabel = uilabel(app.UIFigure);
            app.PolarizationTypeDropDownLabel.Text = 'Polarization Type';
            app.PolarizationTypeDropDown = uidropdown(app.UIFigure);
            app.PolarizationTypeDropDown.Items = {'Vertical', 'Horizontal'};
            app.PolarizationTypeDropDown.Value = 'Vertical';
            app.SurfaceTemperatureCEditFieldLabel = uilabel(app.UIFigure);
            app.SurfaceTemperatureCEditFieldLabel.Text = 'Surface Temperature (°C)';
            app.SurfaceTemperatureCEditField = uieditfield(app.UIFigure, 'numeric');
            app.SurfacePressurehPaEditFieldLabel = uilabel(app.UIFigure);
            app.SurfacePressurehPaEditFieldLabel.Text = 'Surface Pressure (hPa)';
            app.SurfacePressurehPaEditField = uieditfield(app.UIFigure, 'numeric');
            app.RelativeHumidityEditFieldLabel = uilabel(app.UIFigure);
            app.RelativeHumidityEditFieldLabel.Text = 'Relative Humidity (%)';
            app.RelativeHumidityEditField = uieditfield(app.UIFigure, 'numeric');
            app.RainRatemmhrEditFieldLabel = uilabel(app.UIFigure);
            app.RainRatemmhrEditFieldLabel.Text = 'Rain Rate (mm/hr)';
            app.RainRatemmhrEditField = uieditfield(app.UIFigure, 'numeric');
            app.LiquidWaterrTemperatureinCloudsCEditFieldLabel = uilabel(app.UIFigure);
            app.LiquidWaterrTemperatureinCloudsCEditFieldLabel.Text = 'Liquid Waterr Temperature in Clouds (°C)';
            app.LiquidWaterrTemperatureinCloudsCEditField = uieditfield(app.UIFigure, 'numeric');
            app.LiquidWaterDensityinCloudsgm3EditFieldLabel = uilabel(app.UIFigure);
            app.LiquidWaterDensityinCloudsgm3EditFieldLabel.Text = 'Liquid Water Density in Clouds (g/m3)';
            app.LiquidWaterDensityinCloudsgm3EditField = uieditfield(app.UIFigure, 'numeric');
            app.RainAttenuationdBEditFieldLabel = uilabel(app.UIFigure);
            app.RainAttenuationdBEditFieldLabel.Text = 'Rain Attenuation (dB)';
            app.RainAttenuationdBEditField = uieditfield(app.UIFigure, 'numeric');
            app.AtmosphericAttenuationdBEditFieldLabel = uilabel(app.UIFigure);
            app.AtmosphericAttenuationdBEditFieldLabel.Text = 'Atmospheric Attenuation (dB)';
            app.AtmosphericAttenuationdBEditField = uieditfield(app.UIFigure, 'numeric');
            app.CloudAttenuationdBEditFieldLabel = uilabel(app.UIFigure);
            app.CloudAttenuationdBEditFieldLabel.Text = 'Cloud Attenuation (dB)';
            app.CloudAttenuationdBEditField = uieditfield(app.UIFigure, 'numeric');
            app.FreeSpacePathLossdBEditFieldLabel = uilabel(app.UIFigure);
            app.FreeSpacePathLossdBEditFieldLabel.Text = 'Free Space Path Loss (dB)';
            app.FreeSpacePathLossdBEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalAttenuationdBEditFieldLabel = uilabel(app.UIFigure);
            app.TotalAttenuationdBEditFieldLabel.Text = 'Total Attenuation (dB)';
            app.TotalAttenuationdBEditField = uieditfield(app.UIFigure, 'numeric');
            app.CloudBaseHeightkmEditFieldLabel = uilabel(app.UIFigure);
            app.CloudBaseHeightkmEditFieldLabel.Text = 'Cloud Base Height (km)';
            app.CloudBaseHeightkmEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.CalculateButton_2 = uibutton(app.UIFigure, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.CalculateButton_3 = uibutton(app.UIFigure, 'push');
            app.CalculateButton_3.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_3Pushed, true);
            app.CalculateButton_3.Text = 'Calculate';
            app.CalculateButton_4 = uibutton(app.UIFigure, 'push');
            app.CalculateButton_4.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_4Pushed, true);
            app.CalculateButton_4.Text = 'Calculate';
            app.CalculateButton_5 = uibutton(app.UIFigure, 'push');
            app.CalculateButton_5.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_5Pushed, true);
            app.CalculateButton_5.Text = 'Calculate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end